classdef PWMgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        EnableSwitch       matlab.ui.control.ToggleSwitch
        EnableSwitchLabel  matlab.ui.control.Label
        PWMSlider          matlab.ui.control.Slider
        PWMSliderLabel     matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Time % Description
        PWMValues
        numValueToTrack = 100; %how many values to keep track of
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Time=zeros(1,app.numValueToTrack); %app. for getting around rules of variables
            app.PWMValues=zeros(1,app.numValueToTrack);
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            while strcmp(app.EnableSwitch.Value,'On') %string compare of On and Off for switch
                app.PWMValues=[app.PWMValues(2:end),app.PWMSlider.Value];
                app.Time=[app.Time(2:end),app.Time(end)+1]; %for time array that moves like strip chart

                plot(app.UIAxes,app.Time,app.PWMValues) %plot command, plot, xval, yval
                axis(app.UIAxes,[app.Time(1),app.Time(end),-1,251]) %set axis

                drawnow; %tells matlab graphics to update w new data

            end

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.PWMSliderLabel = uilabel(app.GridLayout2);
            app.PWMSliderLabel.Text = 'PWM';
            app.PWMSlider = uislider(app.GridLayout2);
            app.PWMSlider.Limits = [0 250];
            app.EnableSwitchLabel = uilabel(app.GridLayout2);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.GridLayout2, 'toggle');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'PWM')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Value')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PWMgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end